classdef Main_GUI_Page_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        Map_Graph              matlab.ui.control.UIAxes
        uipanel2               matlab.ui.container.Panel
        Enter_Path_Follower    matlab.ui.control.Button
        Exit_Path_Follower     matlab.ui.control.Button
        List_Of_Directions     matlab.ui.control.ListBox
        text2                  matlab.ui.control.Label
        Add_Direction          matlab.ui.control.Button
        Remove_Direction       matlab.ui.control.Button
        Finish_Directions      matlab.ui.control.Button
        Begin_Directions       matlab.ui.control.Button
        uipanel1               matlab.ui.container.Panel
        Exit_Scanning_Mode     matlab.ui.control.Button
        Enter_Scanning_Mode    matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        text3                  matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        X_Coordinate           matlab.ui.control.EditField
        Y_Coordinate           matlab.ui.control.EditField
        Rover_Direction        matlab.ui.control.EditField
        Connect_To_Rover       matlab.ui.control.Button
        Disconnect_From_Rover  matlab.ui.control.Button
        text6                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function Receive_Callback(app)
            SerialPort = getappdata(0,'SerialPort');
            data = fread(SerialPort,'char');
            disp(data);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function Main_GUI_Page_OpeningFcn(app)
             
             app.output = hObject;
             SerialPort = serial('COM14','BaudRate',9600);   %defining serial port COM5 at 9600 baud
             setappdata(0,'SerialPort',SerialPort);         %setting this serial port to app data.
             pause on % to enable pause function
             SerialPort.ReadAsyncMode = 'continuous';
             SerialPort.BytesAvailableFcnCount = 1;
             SerialPort.BytesAvailableFcnMode = 'byte';
             SerialPort.BytesAvailableFcn = @Receive_Callback;
                          
             Directions_Array = [];
             setappdata(0,'Directions_Array',Directions_Array)
             Directions_Display_Array = {};
             setappdata(0,'Directions_Display_Array',Directions_Display_Array)
             set(app.List_Of_Directions,'string',{});
             
             guidata(hObject, handles);
        end

        % Button pushed function: Add_Direction
        function Add_Direction_Callback(app, event)
            set(app.List_Of_Directions,'value',1);
            Add_Direction_GUI
            uiwait(Add_Direction_GUI)
            Duration = getappdata(0,'Duration_Value');
            Direction = getappdata(0,'Direction_Value');
            %[rows,columns] = size(getappdata(0,'Direction_Array'));
            Array = getappdata(0,'Directions_Array');
            Display_Array = getappdata(0,'Directions_Display_Array');
            setappdata(0,'Directions_Array',[Array;Direction,Duration])
            disp(getappdata(0,'Directions_Array'));
            if (Direction == 1)
                str1 = 'Go forward for: ';
                str2 = num2str(Duration);
                str3 = ' milliseconds';
                Final_String = strcat(str1,str2,str3);
            elseif (Direction == 2)
                str1 = 'Go left for: ';
                str2 = num2str(Duration);
                str3 = ' milliseconds';
                Final_String = strcat(str1,str2,str3);
            elseif (Direction == 3)
                str1 = 'Go right for: ';
                str2 = num2str(Duration);
                str3 = ' milliseconds';
                Final_String = strcat(str1,str2,str3);
            end
            
            if (isempty(Display_Array))
            setappdata(0,'Directions_Display_Array',{Final_String})
            else
            setappdata(0,'Directions_Display_Array',[Display_Array; Final_String])
            end
            disp(getappdata(0,'Directions_Display_Array'))
            set(app.List_Of_Directions,'string',getappdata(0,'Directions_Display_Array'))
        end

        % Button pushed function: Begin_Directions
        function Begin_Directions_Callback(app, event)
            fprintf(getappdata(0,'SerialPort'),'c');
        end

        % Button pushed function: Connect_To_Rover
        function Connect_To_Rover_Callback(app, event)
            disp('Connecting...')
            fopen(getappdata(0,'SerialPort'));             %open the serial port
            disp('Connected.')
        end

        % Button pushed function: Disconnect_From_Rover
        function Disconnect_From_Rover_Callback(app, event)
            disp ('Disconnecting...')
            fclose(getappdata(0,'SerialPort'));            %close serial port
%             ser = serial('COM11', 'BaudRate',9600);
%             fclose(ser);
            disp ('Disconnected.')
        end

        % Button pushed function: Enter_Path_Follower
        function Enter_Path_Follower_Callback(app, event)
            fprintf(getappdata(0,'SerialPort'),'a');
        end

        % Button pushed function: Enter_Scanning_Mode
        function Enter_Scanning_Mode_Callback(app, event)
            fprintf(getappdata(0,'SerialPort'),'A');
            x = 0 : 0.01 : pi;
            y = sin(x);
            plot(app.Map_Graph, x, y)
        end

        % Button pushed function: Exit_Path_Follower
        function Exit_Path_Follower_Callback(app, event)
            
            fprintf(getappdata(0,'SerialPort'),'a');
        end

        % Button pushed function: Exit_Scanning_Mode
        function Exit_Scanning_Mode_Callback(app, event)
            fprintf(getappdata(0,'SerialPort'),'B');
        end

        % Button pushed function: Finish_Directions
        function Finish_Directions_Callback(app, event)
            Transmit_All_Directions(getappdata(0,'Directions_Array'));
            fprintf(getappdata(0,'SerialPort'),'d');
        end

        % Button pushed function: Remove_Direction
        function Remove_Direction_Callback(app, event)
            if (~isempty(getappdata(0,'Directions_Array')))
            rowToDelete = get(app.List_Of_Directions,'value');
            set(app.List_Of_Directions,'value',1);
            Array = getappdata(0,'Directions_Array');
            Display_Array = getappdata(0,'Directions_Display_Array');
            Array(rowToDelete, :) = [];
            Display_Array(rowToDelete, :) = [];
            setappdata(0,'Directions_Array', Array);
            setappdata(0,'Directions_Display_Array',Display_Array);
            set(app.List_Of_Directions,'string',Display_Array);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'Main_GUI_Page';
            app.Map_Graph = uiaxes(app.figure1);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Path-Follower Mode';
            app.Enter_Path_Follower = uibutton(app.uipanel2, 'push');
            app.Enter_Path_Follower.ButtonPushedFcn = createCallbackFcn(app, @Enter_Path_Follower_Callback, true);
            app.Enter_Path_Follower.Text = 'Enter Path-Follower Mode';
            app.Exit_Path_Follower = uibutton(app.uipanel2, 'push');
            app.Exit_Path_Follower.ButtonPushedFcn = createCallbackFcn(app, @Exit_Path_Follower_Callback, true);
            app.Exit_Path_Follower.Text = 'Exit Path-Follower Mode';
            app.List_Of_Directions = uilistbox(app.uipanel2);
            app.List_Of_Directions.Items = {};
            app.List_Of_Directions.Value = {};
            app.text2 = uilabel(app.uipanel2);
            app.text2.Text = 'List of Directions';
            app.Add_Direction = uibutton(app.uipanel2, 'push');
            app.Add_Direction.ButtonPushedFcn = createCallbackFcn(app, @Add_Direction_Callback, true);
            app.Add_Direction.Text = 'Add Direction';
            app.Remove_Direction = uibutton(app.uipanel2, 'push');
            app.Remove_Direction.ButtonPushedFcn = createCallbackFcn(app, @Remove_Direction_Callback, true);
            app.Remove_Direction.Text = 'Remove Direction';
            app.Finish_Directions = uibutton(app.uipanel2, 'push');
            app.Finish_Directions.ButtonPushedFcn = createCallbackFcn(app, @Finish_Directions_Callback, true);
            app.Finish_Directions.Text = 'Finish DIrections';
            app.Begin_Directions = uibutton(app.uipanel2, 'push');
            app.Begin_Directions.ButtonPushedFcn = createCallbackFcn(app, @Begin_Directions_Callback, true);
            app.Begin_Directions.Text = 'Begin Directions';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Scanning Mode';
            app.Exit_Scanning_Mode = uibutton(app.uipanel1, 'push');
            app.Exit_Scanning_Mode.ButtonPushedFcn = createCallbackFcn(app, @Exit_Scanning_Mode_Callback, true);
            app.Exit_Scanning_Mode.Text = 'Exit Scanning Mode';
            app.Enter_Scanning_Mode = uibutton(app.uipanel1, 'push');
            app.Enter_Scanning_Mode.ButtonPushedFcn = createCallbackFcn(app, @Enter_Scanning_Mode_Callback, true);
            app.Enter_Scanning_Mode.Text = 'Enter Scanning Mode';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Rover Information';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Text = 'Rover X-Coordinate';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Text = 'Rover Y-Coordinate';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Text = 'Rover Direction (Deg)';
            app.X_Coordinate = uieditfield(app.uipanel3, 'text');
            app.X_Coordinate.Value = '0';
            app.Y_Coordinate = uieditfield(app.uipanel3, 'text');
            app.Y_Coordinate.Value = '0';
            app.Rover_Direction = uieditfield(app.uipanel3, 'text');
            app.Rover_Direction.Value = '90';
            app.Connect_To_Rover = uibutton(app.figure1, 'push');
            app.Connect_To_Rover.ButtonPushedFcn = createCallbackFcn(app, @Connect_To_Rover_Callback, true);
            app.Connect_To_Rover.Text = 'Connect to Rover';
            app.Disconnect_From_Rover = uibutton(app.figure1, 'push');
            app.Disconnect_From_Rover.ButtonPushedFcn = createCallbackFcn(app, @Disconnect_From_Rover_Callback, true);
            app.Disconnect_From_Rover.Text = 'Disconnect from Rover';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = 'Map of Room';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main_GUI_Page_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Main_GUI_Page_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end